classdef appfinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        EditField                       matlab.ui.control.EditField
        Label_2                         matlab.ui.control.Label
        GraphKeyEditField               matlab.ui.control.EditField
        GraphKeyEditFieldLabel          matlab.ui.control.Label
        RestingHeartRateBPMEditField    matlab.ui.control.EditField
        RestingHeartRateBPMEditFieldLabel  matlab.ui.control.Label
        BloodNorepinephrinewhenuprightpgmLEditField_3  matlab.ui.control.EditField
        BloodNorepinephrinewhenuprightpgmLEditField_3Label  matlab.ui.control.Label
        UrineSodiumConcentrationmmolhrEditField  matlab.ui.control.EditField
        UrineSodiumConcentrationmmolhrEditFieldLabel  matlab.ui.control.Label
        ChangeinHeartRateafterHeadUpTiltHUTBPMEditField  matlab.ui.control.EditField
        ChangeinHeartRateafterHeadUpTiltHUTBPMEditFieldLabel  matlab.ui.control.Label
        DailySleepAmounthrsSlider       matlab.ui.control.Slider
        DailySleepAmounthrsSliderLabel  matlab.ui.control.Label
        DailyWaterIntakeozSlider        matlab.ui.control.Slider
        DailyWaterIntakeozSliderLabel   matlab.ui.control.Label
        ChooseDropDown                  matlab.ui.control.DropDown
        ChooseDropDownLabel             matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        CompleteButton                  matlab.ui.control.Button
        ColdIntoleranceCheckBox         matlab.ui.control.CheckBox
        MusclePainCheckBox              matlab.ui.control.CheckBox
        SyncopeCheckBox                 matlab.ui.control.CheckBox
        NumbnessCheckBox                matlab.ui.control.CheckBox
        ReducedSoundToleranceCheckBox   matlab.ui.control.CheckBox
        JointPainCheckBox               matlab.ui.control.CheckBox
        ConstipationCheckBox            matlab.ui.control.CheckBox
        BlurredVisionCheckBox           matlab.ui.control.CheckBox
        SensitivitytoLightCheckBox      matlab.ui.control.CheckBox
        TachycardiaCheckBox             matlab.ui.control.CheckBox
        DiaphoresisCheckBox             matlab.ui.control.CheckBox
        ChestPainCheckBox               matlab.ui.control.CheckBox
        AbdominalPainCheckBox           matlab.ui.control.CheckBox
        JointHypermobilityCheckBox      matlab.ui.control.CheckBox
        EarlySatietyCheckBox            matlab.ui.control.CheckBox
        VenousPoolingCheckBox           matlab.ui.control.CheckBox
        CardiacPalpitationCheckBox      matlab.ui.control.CheckBox
        HeatIntoleranceCheckBox         matlab.ui.control.CheckBox
        ExerciseIntoleranceCheckBox     matlab.ui.control.CheckBox
        NauseaCheckBox                  matlab.ui.control.CheckBox
        DyspneawithactivtiyCheckBox     matlab.ui.control.CheckBox
        InsomniaCheckBox                matlab.ui.control.CheckBox
        HeadachesCheckBox               matlab.ui.control.CheckBox
        BrainFogCheckBox                matlab.ui.control.CheckBox
        FatigueCheckBox                 matlab.ui.control.CheckBox
        PleaseselectwhichofthefollowingsymptomsyouexperienceLabel  matlab.ui.control.Label
        DizzinessCheckBox               matlab.ui.control.CheckBox
        PatientAgeSlider                matlab.ui.control.Slider
        PatientAgeSliderLabel           matlab.ui.control.Label
        POTSDiagnosticToolLabel         matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        age % patient age
        ChangeHR % Patient Resting BPM
        UrineNa % Patient sodium levels
        BloodNorepi % Patient Blood Sodium Levels
        score % Added total
        RHR % Resting Heart Rate
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PatientAgeSlider
        function PatientAgeSliderValueChanged(app, event)
            app.age = app.PatientAgeSlider.Value;
            app.score = 0;
            if app.age > 15 && app.age < 50
                app.score = app.score + 3;
            end
        end

        % Value changed function: 
        % BloodNorepinephrinewhenuprightpgmLEditField_3
        function BloodNorepinephrinewhenuprightpgmLEditField_3ValueChanged(app, event)
            app.BloodNorepi = str2num(app.BloodNorepinephrinewhenuprightpgmLEditField_3.Value);
            if app.BloodNorepi > 600
                app.score = app.score + 1;
            end
        end

        % Value changed function: UrineSodiumConcentrationmmolhrEditField
        function UrineSodiumConcentrationmmolhrEditFieldValueChanged(app, event)
            app.UrineNa = str2num(app.UrineSodiumConcentrationmmolhrEditField.Value);
            if app.UrineNa < 124
                app.score = app.score + 1;
            end
        end

        % Value changed function: RestingHeartRateBPMEditField
        function RestingHeartRateBPMEditFieldValueChanged(app, event)
            app.RHR = str2num(app.RestingHeartRateBPMEditField.Value);
            if app.RHR > 100
                app.score = app.score + 3;
            end
        end

        % Value changed function: 
        % ChangeinHeartRateafterHeadUpTiltHUTBPMEditField
        function ChangeinHeartRateafterHeadUpTiltHUTBPMEditFieldValueChanged(app, event)
            app.ChangeHR = str2num(app.ChangeinHeartRateafterHeadUpTiltHUTBPMEditField.Value);
            if app.ChangeHR > 40
                app.score = app.score + 5;
            elseif app.ChangeHR > 30
                app.score = app.score + 3;
            end
        end

        % Value changed function: DailySleepAmounthrsSlider
        function DailySleepAmounthrsSliderValueChanged(app, event)
            sleep = app.DailySleepAmounthrsSlider.Value;
            if sleep < 8
                app.score = app.score + 1;
            end
        end

        % Value changed function: DailyWaterIntakeozSlider
        function DailyWaterIntakeozSliderValueChanged(app, event)
            water = app.DailyWaterIntakeozSlider.Value;
            if water < 27
                app.score = app.score + 1;
            end
        end

        % Value changed function: ChooseDropDown
        function ChooseDropDownValueChanged(app, event)
            trauma = app.ChooseDropDown.Value;
            if trauma ~= "None"
                app.score = app.score + 1;
            end
        end

        % Value changed function: DizzinessCheckBox
        function DizzinessCheckBoxValueChanged(app, event)
            dizzy = app.DizzinessCheckBox.Value;
            if dizzy
                app.score = app.score + 1;
            end
        end

        % Value changed function: BrainFogCheckBox
        function BrainFogCheckBoxValueChanged(app, event)
            brainFog = app.BrainFogCheckBox.Value;
            if brainFog
                app.score = app.score + 1;
            end
        end

        % Value changed function: InsomniaCheckBox
        function InsomniaCheckBoxValueChanged(app, event)
            insomnia = app.InsomniaCheckBox.Value;
            if insomnia
                app.score = app.score + 1;
            end
        end

        % Value changed function: NauseaCheckBox
        function NauseaCheckBoxValueChanged(app, event)
            nausea = app.NauseaCheckBox.Value;
            if nausea
                app.score = app.score + 1;
            end
        end

        % Value changed function: HeatIntoleranceCheckBox
        function HeatIntoleranceCheckBoxValueChanged(app, event)
            heatintol = app.HeatIntoleranceCheckBox.Value;
            if heatintol
                app.score = app.score + 1;
            end
        end

        % Value changed function: VenousPoolingCheckBox
        function VenousPoolingCheckBoxValueChanged(app, event)
            venpool = app.VenousPoolingCheckBox.Value;
            if venpool
                app.score = app.score + 1;
            end
        end

        % Value changed function: FatigueCheckBox
        function FatigueCheckBoxValueChanged(app, event)
            fatigue = app.FatigueCheckBox.Value;
            if fatigue
                app.score = app.score + 1;
            end
        end

        % Value changed function: HeadachesCheckBox
        function HeadachesCheckBoxValueChanged(app, event)
            headaches = app.HeadachesCheckBox.Value;
            if headaches
                app.score = app.score + 1;
            end
        end

        % Value changed function: DyspneawithactivtiyCheckBox
        function DyspneawithactivityCheckBoxValueChanged(app, event)
            dyspnea = app.DyspneawithactivtiyCheckBox.Value;
            if dyspnea
                app.score = app.score + 1;
            end
        end

        % Value changed function: ExerciseIntoleranceCheckBox
        function ExerciseIntoleranceCheckBoxValueChanged(app, event)
            exintol = app.ExerciseIntoleranceCheckBox.Value;
            if exintol
                app.score = app.score + 1;
            end
        end

        % Value changed function: CardiacPalpitationCheckBox
        function CardiacPalpitationCheckBoxValueChanged(app, event)
            carpal = app.CardiacPalpitationCheckBox.Value;
            if carpal
                app.score = app.score + 1;
            end
        end

        % Value changed function: EarlySatietyCheckBox
        function EarlySatietyCheckBoxValueChanged(app, event)
            satiety = app.EarlySatietyCheckBox.Value;
            if satiety
                app.score = app.score + 1;
            end
        end

        % Value changed function: JointHypermobilityCheckBox
        function JointHypermobilityCheckBoxValueChanged(app, event)
            hypermob = app.JointHypermobilityCheckBox.Value;
            if hypermob
                app.score = app.score + 1;
            end
        end

        % Value changed function: ChestPainCheckBox
        function ChestPainCheckBoxValueChanged(app, event)
            cpain = app.ChestPainCheckBox.Value;
            if cpain
                app.score = app.score + 1;
            end
        end

        % Value changed function: TachycardiaCheckBox
        function TachycardiaCheckBoxValueChanged(app, event)
            tachy = app.TachycardiaCheckBox.Value;
            if tachy
                app.score = app.score + 1;
            end
        end

        % Value changed function: BlurredVisionCheckBox
        function BlurredVisionCheckBoxValueChanged(app, event)
            blurvis = app.BlurredVisionCheckBox.Value;
            if blurvis
                app.score = app.score + 1;
            end
        end

        % Value changed function: JointPainCheckBox
        function JointPainCheckBoxValueChanged(app, event)
            jointpain = app.JointPainCheckBox.Value;
            if jointpain
                app.score = app.score + 1;
            end
        end

        % Value changed function: NumbnessCheckBox
        function NumbnessCheckBoxValueChanged(app, event)
            numb = app.NumbnessCheckBox.Value;
            if numb
                app.score = app.score + 1;
            end
        end

        % Value changed function: MusclePainCheckBox
        function MusclePainCheckBoxValueChanged(app, event)
            muscpain = app.MusclePainCheckBox.Value;
            if muscpain
                app.score = app.score + 1;
            end
        end

        % Value changed function: AbdominalPainCheckBox
        function AbdominalPainCheckBoxValueChanged(app, event)
            abpain = app.AbdominalPainCheckBox.Value;
            if abpain
                app.score = app.score + 1;
            end
        end

        % Value changed function: DiaphoresisCheckBox
        function DiaphoresisCheckBoxValueChanged(app, event)
            diaphor = app.DiaphoresisCheckBox.Value;
            if diaphor
                app.score = app.score + 1;
            end
        end

        % Value changed function: SensitivitytoLightCheckBox
        function SensitivitytoLightCheckBoxValueChanged(app, event)
            light = app.SensitivitytoLightCheckBox.Value;
            if light
                app.score = app.score + 1;
            end
        end

        % Value changed function: ConstipationCheckBox
        function ConstipationCheckBoxValueChanged(app, event)
            constip = app.ConstipationCheckBox.Value;
            if constip
                app.score = app.score + 1;
            end
        end

        % Value changed function: ReducedSoundToleranceCheckBox
        function ReducedSoundToleranceCheckBoxValueChanged(app, event)
            sound = app.ReducedSoundToleranceCheckBox.Value;
            if sound
                app.score = app.score + 1;
            end
        end

        % Value changed function: SyncopeCheckBox
        function SyncopeCheckBoxValueChanged(app, event)
            syncope = app.SyncopeCheckBox.Value;
            if syncope
                app.score = app.score + 1;
            end
        end

        % Value changed function: ColdIntoleranceCheckBox
        function ColdIntoleranceCheckBoxValueChanged(app, event)
            coldintol = app.ColdIntoleranceCheckBox.Value;
            if coldintol
                app.score = app.score + 1;
            end
        end

        % Button pushed function: CompleteButton
        function CompleteButtonPushed(app, event)
            % RHR
            plot(app.UIAxes, 40:140, 0.25, '+m');
            hold on
            plot(app.UIAxes, 100:140, 0.25, '+c');
            hold on
            plot(app.UIAxes, app.RHR, 0.25, 'ok');
            % text(app.UIAxes, 0, 0.025, '\leftarrow', 'HorizontalAlignment','left', 'VerticalAlignment','middle', 'FontSize',18);
            % text(app.UIAxes, 100, 0.025, '\rightarrow', 'HorizontalAlignment','right', 'VerticalAlignment','middle', 'FontSize',18);
            % set(app.UIAxes, gca, 'XAxisLocation','origin', 'YColor','none');
            title(app.UIAxes, 'Resting Heart Rate');
            xlabel(app.UIAxes, 'Heart Rate (bpm)');

            %Change in HR
            trial = 1; 
            plot(app.UIAxes2, [0 2], [35 35], 'r');
            hold on
            bar(app.UIAxes2, trial, app.ChangeHR);
            % ylim(app.UIAxes2, [0 60]);
            title(app.UIAxes2, 'Average Change in Heart Rate After HUT');
            ylabel(app.UIAxes2, 'Change in Heart Rate (bpm)');
            xlabel(app.UIAxes2, 'Table Tilt Test');

            %Urine Na
            plot(app.UIAxes3, 0:220, 0.25, '+m');
            hold on
            plot(app.UIAxes3, 0:124, 0.25, '+c');
            hold on
            plot(app.UIAxes3, (app.UrineNa), 0.25, 'ok');
            % text(app.UIAxes3, 0, 0.025, '\leftarrow', 'HorizontalAlignment','left', 'VerticalAlignment','middle', 'FontSize',18);
            % text(app.UIAxes3, 100, 0.025, '\rightarrow', 'HorizontalAlignment','right', 'VerticalAlignment','middle', 'FontSize',18);
            % set(app.UIAxes3, gca, 'XAxisLocation','origin', 'YColor','none');
            title(app.UIAxes3, 'Urine Sodium Concentration');
            xlabel(app.UIAxes3, 'Urine Sodium Concentration (mmol/hr)');

            %Blood Nnorepinephrine
            plot(app.UIAxes4, 70:1700, 0.25, '+m');
            hold on
            plot(app.UIAxes4, 600:1700, 0.25, '+c');
            hold on
            plot(app.UIAxes4, (app.BloodNorepi), 0.25, 'ok');
            % text(app.UIAxes4, 0, 0.025, '\leftarrow', 'HorizontalAlignment','left', 'VerticalAlignment','middle', 'FontSize',18);
            % text(app.UIAxes4, 100, 0.025, '\rightarrow', 'HorizontalAlignment','right', 'VerticalAlignment','middle', 'FontSize',18);
            % set(app.UIAxes4, gca, 'XAxisLocation','origin', 'YColor','none');
            title(app.UIAxes4, 'Blood Norepinephrine Levels');
            xlabel(app.UIAxes4, 'Norepineprhine Level (pg,mL)');

            if app.score > 12
                msgbox("Pursue POTS Diagnosis");
            else
                msgbox("POTS Diagnosis Unlikely");
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {965, 965};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {353, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.POTSDiagnosticToolLabel = uilabel(app.LeftPanel);
            app.POTSDiagnosticToolLabel.Text = 'POTS Diagnostic Tool';
            app.PatientAgeSliderLabel = uilabel(app.LeftPanel);
            app.PatientAgeSliderLabel.Text = 'Patient Age:';
            app.PatientAgeSlider = uislider(app.LeftPanel);
            app.PatientAgeSlider.Limits = [0 50];
            app.PatientAgeSlider.ValueChangedFcn = createCallbackFcn(app, @PatientAgeSliderValueChanged, true);
            app.PatientAgeSlider.Value = 8;
            app.DizzinessCheckBox = uicheckbox(app.LeftPanel);
            app.DizzinessCheckBox.ValueChangedFcn = createCallbackFcn(app, @DizzinessCheckBoxValueChanged, true);
            app.DizzinessCheckBox.Text = 'Dizziness';
            app.PleaseselectwhichofthefollowingsymptomsyouexperienceLabel = uilabel(app.LeftPanel);
            app.PleaseselectwhichofthefollowingsymptomsyouexperienceLabel.Text = 'Please select which of the following symptoms your patient is experiencing that are not better explained by a different diagnosis:';
            app.FatigueCheckBox = uicheckbox(app.LeftPanel);
            app.FatigueCheckBox.ValueChangedFcn = createCallbackFcn(app, @FatigueCheckBoxValueChanged, true);
            app.FatigueCheckBox.Text = 'Fatigue';
            app.BrainFogCheckBox = uicheckbox(app.LeftPanel);
            app.BrainFogCheckBox.ValueChangedFcn = createCallbackFcn(app, @BrainFogCheckBoxValueChanged, true);
            app.BrainFogCheckBox.Text = 'Brain Fog';
            app.HeadachesCheckBox = uicheckbox(app.LeftPanel);
            app.HeadachesCheckBox.ValueChangedFcn = createCallbackFcn(app, @HeadachesCheckBoxValueChanged, true);
            app.HeadachesCheckBox.Text = 'Headaches';
            app.InsomniaCheckBox = uicheckbox(app.LeftPanel);
            app.InsomniaCheckBox.ValueChangedFcn = createCallbackFcn(app, @InsomniaCheckBoxValueChanged, true);
            app.InsomniaCheckBox.Text = 'Insomnia';
            app.DyspneawithactivtiyCheckBox = uicheckbox(app.LeftPanel);
            app.DyspneawithactivtiyCheckBox.ValueChangedFcn = createCallbackFcn(app, @DyspneawithactivityCheckBoxValueChanged, true);
            app.DyspneawithactivtiyCheckBox.Text = 'Dyspnea with activtiy';
            app.NauseaCheckBox = uicheckbox(app.LeftPanel);
            app.NauseaCheckBox.ValueChangedFcn = createCallbackFcn(app, @NauseaCheckBoxValueChanged, true);
            app.NauseaCheckBox.Text = 'Nausea';
            app.ExerciseIntoleranceCheckBox = uicheckbox(app.LeftPanel);
            app.ExerciseIntoleranceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExerciseIntoleranceCheckBoxValueChanged, true);
            app.ExerciseIntoleranceCheckBox.Text = 'Exercise Intolerance';
            app.HeatIntoleranceCheckBox = uicheckbox(app.LeftPanel);
            app.HeatIntoleranceCheckBox.ValueChangedFcn = createCallbackFcn(app, @HeatIntoleranceCheckBoxValueChanged, true);
            app.HeatIntoleranceCheckBox.Text = 'Heat Intolerance';
            app.CardiacPalpitationCheckBox = uicheckbox(app.LeftPanel);
            app.CardiacPalpitationCheckBox.ValueChangedFcn = createCallbackFcn(app, @CardiacPalpitationCheckBoxValueChanged, true);
            app.CardiacPalpitationCheckBox.Text = 'Cardiac Palpitation';
            app.VenousPoolingCheckBox = uicheckbox(app.LeftPanel);
            app.VenousPoolingCheckBox.ValueChangedFcn = createCallbackFcn(app, @VenousPoolingCheckBoxValueChanged, true);
            app.VenousPoolingCheckBox.Text = 'Venous Pooling';
            app.EarlySatietyCheckBox = uicheckbox(app.LeftPanel);
            app.EarlySatietyCheckBox.ValueChangedFcn = createCallbackFcn(app, @EarlySatietyCheckBoxValueChanged, true);
            app.EarlySatietyCheckBox.Text = 'Early Satiety';
            app.JointHypermobilityCheckBox = uicheckbox(app.LeftPanel);
            app.JointHypermobilityCheckBox.ValueChangedFcn = createCallbackFcn(app, @JointHypermobilityCheckBoxValueChanged, true);
            app.JointHypermobilityCheckBox.Text = 'Joint Hypermobility';
            app.AbdominalPainCheckBox = uicheckbox(app.LeftPanel);
            app.AbdominalPainCheckBox.ValueChangedFcn = createCallbackFcn(app, @AbdominalPainCheckBoxValueChanged, true);
            app.AbdominalPainCheckBox.Text = 'Abdominal Pain';
            app.ChestPainCheckBox = uicheckbox(app.LeftPanel);
            app.ChestPainCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChestPainCheckBoxValueChanged, true);
            app.ChestPainCheckBox.Text = 'Chest Pain';
            app.DiaphoresisCheckBox = uicheckbox(app.LeftPanel);
            app.DiaphoresisCheckBox.ValueChangedFcn = createCallbackFcn(app, @DiaphoresisCheckBoxValueChanged, true);
            app.DiaphoresisCheckBox.Text = 'Diaphoresis';
            app.TachycardiaCheckBox = uicheckbox(app.LeftPanel);
            app.TachycardiaCheckBox.ValueChangedFcn = createCallbackFcn(app, @TachycardiaCheckBoxValueChanged, true);
            app.TachycardiaCheckBox.Text = 'Tachycardia';
            app.SensitivitytoLightCheckBox = uicheckbox(app.LeftPanel);
            app.SensitivitytoLightCheckBox.ValueChangedFcn = createCallbackFcn(app, @SensitivitytoLightCheckBoxValueChanged, true);
            app.SensitivitytoLightCheckBox.Text = 'Sensitivity to Light';
            app.BlurredVisionCheckBox = uicheckbox(app.LeftPanel);
            app.BlurredVisionCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlurredVisionCheckBoxValueChanged, true);
            app.BlurredVisionCheckBox.Text = 'Blurred Vision';
            app.ConstipationCheckBox = uicheckbox(app.LeftPanel);
            app.ConstipationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConstipationCheckBoxValueChanged, true);
            app.ConstipationCheckBox.Text = 'Constipation';
            app.JointPainCheckBox = uicheckbox(app.LeftPanel);
            app.JointPainCheckBox.ValueChangedFcn = createCallbackFcn(app, @JointPainCheckBoxValueChanged, true);
            app.JointPainCheckBox.Text = 'Joint Pain';
            app.ReducedSoundToleranceCheckBox = uicheckbox(app.LeftPanel);
            app.ReducedSoundToleranceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReducedSoundToleranceCheckBoxValueChanged, true);
            app.ReducedSoundToleranceCheckBox.Text = 'Reduced Sound Tolerance';
            app.NumbnessCheckBox = uicheckbox(app.LeftPanel);
            app.NumbnessCheckBox.ValueChangedFcn = createCallbackFcn(app, @NumbnessCheckBoxValueChanged, true);
            app.NumbnessCheckBox.Text = 'Numbness';
            app.SyncopeCheckBox = uicheckbox(app.LeftPanel);
            app.SyncopeCheckBox.ValueChangedFcn = createCallbackFcn(app, @SyncopeCheckBoxValueChanged, true);
            app.SyncopeCheckBox.Text = 'Syncope';
            app.MusclePainCheckBox = uicheckbox(app.LeftPanel);
            app.MusclePainCheckBox.ValueChangedFcn = createCallbackFcn(app, @MusclePainCheckBoxValueChanged, true);
            app.MusclePainCheckBox.Text = 'Muscle Pain';
            app.ColdIntoleranceCheckBox = uicheckbox(app.LeftPanel);
            app.ColdIntoleranceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColdIntoleranceCheckBoxValueChanged, true);
            app.ColdIntoleranceCheckBox.Text = 'Cold Intolerance';
            app.CompleteButton = uibutton(app.LeftPanel, 'push');
            app.CompleteButton.ButtonPushedFcn = createCallbackFcn(app, @CompleteButtonPushed, true);
            app.CompleteButton.Text = 'Complete';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'Has your patient experienced any of the following in the last five (5) months:';
            app.ChooseDropDownLabel = uilabel(app.LeftPanel);
            app.ChooseDropDownLabel.Text = 'Choose:';
            app.ChooseDropDown = uidropdown(app.LeftPanel);
            app.ChooseDropDown.Items = {'Menarche', 'Pregnancy', 'Puberty', 'Concussion', 'Infection', 'None'};
            app.ChooseDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseDropDownValueChanged, true);
            app.ChooseDropDown.Value = 'Menarche';
            app.DailyWaterIntakeozSliderLabel = uilabel(app.LeftPanel);
            app.DailyWaterIntakeozSliderLabel.Text = 'Daily Water Intake (oz)';
            app.DailyWaterIntakeozSlider = uislider(app.LeftPanel);
            app.DailyWaterIntakeozSlider.Limits = [0 64];
            app.DailyWaterIntakeozSlider.ValueChangedFcn = createCallbackFcn(app, @DailyWaterIntakeozSliderValueChanged, true);
            app.DailySleepAmounthrsSliderLabel = uilabel(app.LeftPanel);
            app.DailySleepAmounthrsSliderLabel.Text = 'Daily Sleep Amount (hrs)';
            app.DailySleepAmounthrsSlider = uislider(app.LeftPanel);
            app.DailySleepAmounthrsSlider.Limits = [0 12];
            app.DailySleepAmounthrsSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.DailySleepAmounthrsSlider.ValueChangedFcn = createCallbackFcn(app, @DailySleepAmounthrsSliderValueChanged, true);
            app.ChangeinHeartRateafterHeadUpTiltHUTBPMEditFieldLabel = uilabel(app.LeftPanel);
            app.ChangeinHeartRateafterHeadUpTiltHUTBPMEditFieldLabel.Text = 'Change in Heart Rate after Head Up Tilt (HUT) (BPM)';
            app.ChangeinHeartRateafterHeadUpTiltHUTBPMEditField = uieditfield(app.LeftPanel, 'text');
            app.ChangeinHeartRateafterHeadUpTiltHUTBPMEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeinHeartRateafterHeadUpTiltHUTBPMEditFieldValueChanged, true);
            app.UrineSodiumConcentrationmmolhrEditFieldLabel = uilabel(app.LeftPanel);
            app.UrineSodiumConcentrationmmolhrEditFieldLabel.Text = 'Urine Sodium Concentration (mmol/hr)';
            app.UrineSodiumConcentrationmmolhrEditField = uieditfield(app.LeftPanel, 'text');
            app.UrineSodiumConcentrationmmolhrEditField.ValueChangedFcn = createCallbackFcn(app, @UrineSodiumConcentrationmmolhrEditFieldValueChanged, true);
            app.BloodNorepinephrinewhenuprightpgmLEditField_3Label = uilabel(app.LeftPanel);
            app.BloodNorepinephrinewhenuprightpgmLEditField_3Label.Text = 'Blood Norepinephrine when upright (pg/mL)';
            app.BloodNorepinephrinewhenuprightpgmLEditField_3 = uieditfield(app.LeftPanel, 'text');
            app.BloodNorepinephrinewhenuprightpgmLEditField_3.ValueChangedFcn = createCallbackFcn(app, @BloodNorepinephrinewhenuprightpgmLEditField_3ValueChanged, true);
            app.RestingHeartRateBPMEditFieldLabel = uilabel(app.LeftPanel);
            app.RestingHeartRateBPMEditFieldLabel.Text = 'Resting Heart Rate (BPM)';
            app.RestingHeartRateBPMEditField = uieditfield(app.LeftPanel, 'text');
            app.RestingHeartRateBPMEditField.ValueChangedFcn = createCallbackFcn(app, @RestingHeartRateBPMEditFieldValueChanged, true);
            app.GraphKeyEditFieldLabel = uilabel(app.LeftPanel);
            app.GraphKeyEditFieldLabel.Text = 'Graph Key:';
            app.GraphKeyEditField = uieditfield(app.LeftPanel, 'text');
            app.GraphKeyEditField.Value = 'Cyan- in the range of POTS symptoms';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = ' ';
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.EditField.Value = 'Magenta- not in the range of POTS symptoms';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Resting Heart Rate')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, ' ')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = 0.25;
            app.UIAxes.YTickLabel = {''};
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Change in BPM')
            xlabel(app.UIAxes2, 'Trial')
            ylabel(app.UIAxes2, 'Change in HR')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTickLabel = {''};
            app.UIAxes2.YTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'};
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Urine Soidum')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, ' ')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'};
            app.UIAxes3.YTickLabel = {''};
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'Norepinephrine')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, ' ')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.YTick = 0.25;
            app.UIAxes4.YTickLabel = {''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appfinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end